classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Question1                     matlab.ui.container.Tab
        GridCheckBox                  matlab.ui.control.CheckBox
        ChooseuptoFourcountriesLabel  matlab.ui.control.Label
        EndLabel                      matlab.ui.control.Label
        StartLabel                    matlab.ui.control.Label
        EndEditField                  matlab.ui.control.NumericEditField
        StartEditField                matlab.ui.control.NumericEditField
        ListBox                       matlab.ui.control.ListBox
        GraphButton                   matlab.ui.control.Button
        MedalDropDownLabel            matlab.ui.control.Label
        MedalDropDown                 matlab.ui.control.DropDown
        SportDropDown                 matlab.ui.control.DropDown
        SportDropDownLabel            matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        Question2                     matlab.ui.container.Tab
        CalculateButton               matlab.ui.control.Button
        TotalEditField_5              matlab.ui.control.NumericEditField
        TotalEditField_5Label         matlab.ui.control.Label
        TotalEditField_4              matlab.ui.control.NumericEditField
        TotalEditField_4Label         matlab.ui.control.Label
        TotalEditField_3              matlab.ui.control.NumericEditField
        TotalEditField_3Label         matlab.ui.control.Label
        TotalEditField_2              matlab.ui.control.NumericEditField
        TotalEditField_2Label         matlab.ui.control.Label
        GoldEditField_5               matlab.ui.control.NumericEditField
        GoldEditField_5Label          matlab.ui.control.Label
        GoldEditField_4               matlab.ui.control.NumericEditField
        GoldEditField_4Label          matlab.ui.control.Label
        GoldEditField_3               matlab.ui.control.NumericEditField
        GoldEditField_3Label          matlab.ui.control.Label
        GoldEditField_2               matlab.ui.control.NumericEditField
        GoldEditField_2Label          matlab.ui.control.Label
        SilverEditField_5             matlab.ui.control.NumericEditField
        SilverEditField_5Label        matlab.ui.control.Label
        SilverEditField_4             matlab.ui.control.NumericEditField
        SilverEditField_4Label        matlab.ui.control.Label
        SilverEditField_3             matlab.ui.control.NumericEditField
        SilverEditField_3Label        matlab.ui.control.Label
        SilverEditField_2             matlab.ui.control.NumericEditField
        SilverEditField_2Label        matlab.ui.control.Label
        BronzeEditField_5             matlab.ui.control.NumericEditField
        BronzeEditField_5Label        matlab.ui.control.Label
        BronzeEditField_4             matlab.ui.control.NumericEditField
        BronzeEditField_4Label        matlab.ui.control.Label
        BronzeEditField_3             matlab.ui.control.NumericEditField
        BronzeEditField_3Label        matlab.ui.control.Label
        BronzeEditField_2             matlab.ui.control.NumericEditField
        BronzeEditField_2Label        matlab.ui.control.Label
        TotalEditField                matlab.ui.control.NumericEditField
        TotalEditFieldLabel           matlab.ui.control.Label
        GoldEditField                 matlab.ui.control.NumericEditField
        GoldEditFieldLabel            matlab.ui.control.Label
        SilverEditField               matlab.ui.control.NumericEditField
        SilverEditFieldLabel          matlab.ui.control.Label
        BronzeEditField               matlab.ui.control.NumericEditField
        BronzeEditFieldLabel          matlab.ui.control.Label
        Event1DropDown                matlab.ui.control.DropDown
        Event1DropDownLabel           matlab.ui.control.Label
        Event5DropDown                matlab.ui.control.DropDown
        Event5DropDownLabel           matlab.ui.control.Label
        Event4DropDown                matlab.ui.control.DropDown
        Event4DropDownLabel           matlab.ui.control.Label
        Event3DropDown                matlab.ui.control.DropDown
        Event3DropDownLabel           matlab.ui.control.Label
        Event2DropDown                matlab.ui.control.DropDown
        Event2DropDownLabel           matlab.ui.control.Label
        Country_EditField_10          matlab.ui.control.EditField
        Country_EditField_9           matlab.ui.control.EditField
        Country_EditField_8           matlab.ui.control.EditField
        Country_EditField_7           matlab.ui.control.EditField
        Country_EditField_6           matlab.ui.control.EditField
        Country_EditField_5           matlab.ui.control.EditField
        Country_EditField_4           matlab.ui.control.EditField
        Country_EditField_3           matlab.ui.control.EditField
        Country_EditField_2           matlab.ui.control.EditField
        AthleteLabel                  matlab.ui.control.Label
        SportLabel                    matlab.ui.control.Label
        Country_EditField             matlab.ui.control.EditField
        ofMedalsLabel                 matlab.ui.control.Label
        Question3                     matlab.ui.container.Tab
        EditField_5                   matlab.ui.control.EditField
        EditField_4                   matlab.ui.control.EditField
        EditField_3                   matlab.ui.control.EditField
        EditField_2                   matlab.ui.control.EditField
        EditField                     matlab.ui.control.EditField
        ComputeButton                 matlab.ui.control.Button
        ofMedalsLabel_2               matlab.ui.control.Label
        CountryNameLabel              matlab.ui.control.Label
        TotalEditField_10             matlab.ui.control.NumericEditField
        TotalEditField_10Label        matlab.ui.control.Label
        TotalEditField_9              matlab.ui.control.NumericEditField
        TotalEditField_9Label         matlab.ui.control.Label
        TotalEditField_8              matlab.ui.control.NumericEditField
        TotalEditField_8Label         matlab.ui.control.Label
        TotalEditField_7              matlab.ui.control.NumericEditField
        TotalEditField_7Label         matlab.ui.control.Label
        GoldEditField_10              matlab.ui.control.NumericEditField
        GoldEditField_10Label         matlab.ui.control.Label
        GoldEditField_9               matlab.ui.control.NumericEditField
        GoldEditField_9Label          matlab.ui.control.Label
        GoldEditField_8               matlab.ui.control.NumericEditField
        GoldEditField_8Label          matlab.ui.control.Label
        GoldEditField_7               matlab.ui.control.NumericEditField
        GoldEditField_7Label          matlab.ui.control.Label
        SilverEditField_10            matlab.ui.control.NumericEditField
        SilverEditField_10Label       matlab.ui.control.Label
        SilverEditField_9             matlab.ui.control.NumericEditField
        SilverEditField_9Label        matlab.ui.control.Label
        SilverEditField_8             matlab.ui.control.NumericEditField
        SilverEditField_8Label        matlab.ui.control.Label
        SilverEditField_7             matlab.ui.control.NumericEditField
        SilverEditField_7Label        matlab.ui.control.Label
        BronzeEditField_10            matlab.ui.control.NumericEditField
        BronzeEditField_10Label       matlab.ui.control.Label
        BronzeEditField_9             matlab.ui.control.NumericEditField
        BronzeEditField_9Label        matlab.ui.control.Label
        BronzeEditField_8             matlab.ui.control.NumericEditField
        BronzeEditField_8Label        matlab.ui.control.Label
        BronzeEditField_7             matlab.ui.control.NumericEditField
        BronzeEditField_7Label        matlab.ui.control.Label
        TotalEditField_6              matlab.ui.control.NumericEditField
        TotalEditField_6Label         matlab.ui.control.Label
        GoldEditField_6               matlab.ui.control.NumericEditField
        GoldEditField_6Label          matlab.ui.control.Label
        SilverEditField_6             matlab.ui.control.NumericEditField
        SilverEditField_6Label        matlab.ui.control.Label
        BronzeEditField_6             matlab.ui.control.NumericEditField
        BronzeEditField_6Label        matlab.ui.control.Label
        Country5DropDown              matlab.ui.control.DropDown
        Country5DropDownLabel         matlab.ui.control.Label
        Country4DropDown              matlab.ui.control.DropDown
        Country4DropDownLabel         matlab.ui.control.Label
        Country3DropDown              matlab.ui.control.DropDown
        Country3DropDownLabel         matlab.ui.control.Label
        Country2DropDown              matlab.ui.control.DropDown
        Country2DropDownLabel         matlab.ui.control.Label
        Country1DropDown              matlab.ui.control.DropDown
        Country1DropDownLabel         matlab.ui.control.Label
        Question4                     matlab.ui.container.Tab
        GridCheckBox_2                matlab.ui.control.CheckBox
        GraphButton_2                 matlab.ui.control.Button
        MedalDropDown_2               matlab.ui.control.DropDown
        MedalDropDown_2Label          matlab.ui.control.Label
        SportDropDown_2               matlab.ui.control.DropDown
        SportDropDown_2Label          matlab.ui.control.Label
        GenderDropDown                matlab.ui.control.DropDown
        GenderDropDownLabel           matlab.ui.control.Label
        UIAxes2                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            barGraph(app)
            
        end

        % Button pushed function: GraphButton_2
        function GraphButton_2Pushed(app, event)
            barGraph2(app)
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            app.EditField.Value=app.Country1DropDown.Value;
            app.EditField_2.Value=app.Country2DropDown.Value;
            app.EditField_3.Value=app.Country3DropDown.Value;
            app.EditField_4.Value=app.Country4DropDown.Value;
            app.EditField_5.Value=app.Country5DropDown.Value;
            
            question3(app)


        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.Country_EditField.Value=app.Event1DropDown.Value;
            app.Country_EditField_3.Value=app.Event2DropDown.Value;
            app.Country_EditField_4.Value= app.Event3DropDown.Value;
            app.Country_EditField_5.Value = app.Event4DropDown.Value;
            app.Country_EditField_6.Value = app.Event5DropDown.Value;
            question2(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Question1 = uitab(app.TabGroup);
            app.Question1.Title = 'Question 1';
            app.UIAxes = uiaxes(app.Question1);
            title(app.UIAxes, 'Comparison Bar Graph')
            xlabel(app.UIAxes, 'Country Names')
            ylabel(app.UIAxes, 'Number of Medals')
            zlabel(app.UIAxes, 'Z')
            app.SportDropDownLabel = uilabel(app.Question1);
            app.SportDropDownLabel.Text = 'Sport';
            app.SportDropDown = uidropdown(app.Question1);
            app.SportDropDown.Items = {'Basketball', 'Basque Pelota', 'Beach Volleyball', 'Biathlon', 'Bobsleigh', 'Boxing', 'Canoeing', 'Cricket', 'Croquet', 'Cross Country Skiing', 'Curling', 'Cycling', 'Diving', 'Equestrianism', 'Fencing', 'Figure Skating', 'Football', 'Freestyle Skiing', 'Golf', 'Gymnastics', 'Handball', 'Hockey', 'Ice Hockey', 'Jeu De Paume', 'Judo', 'Lacrosse', 'Luge', 'Military Ski Patrol', 'Modern Pentathlon', 'Motorboating', 'Nordic Combined', 'Polo', 'Racquets', 'Rhythmic Gymnastics', 'Roque', 'Rowing', 'Rugby', 'Rugby Sevens', 'Sailing', 'Shooting', 'Short Track Speed Skating', 'Skeleton', 'Ski Jumping', 'Snowboarding', 'Softball', 'Speed Skating', 'Swimming', 'Synchronized Swimming', 'Table Tennis', 'Taekwondo', 'Tennis', 'Trampolining', 'Triathlon', 'Tug-Of-War', 'Volleyball', 'Water Polo', 'Weightlifting', 'Wrestling'};
            app.SportDropDown.Value = 'Basketball';
            app.MedalDropDown = uidropdown(app.Question1);
            app.MedalDropDown.Items = {'Gold', 'Silver', 'Bronze'};
            app.MedalDropDown.Value = 'Gold';
            app.MedalDropDownLabel = uilabel(app.Question1);
            app.MedalDropDownLabel.Text = 'Medal';
            app.GraphButton = uibutton(app.Question1, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.ListBox = uilistbox(app.Question1);
            app.ListBox.Items = {'Afghanistan', 'Algeria', 'Argentina', 'Armenia', 'Australia', 'Australia and New Zealand', 'Austria', 'Azerbaijan', 'Bahamas', 'Bahrain', 'Barbados', 'Belarus', 'Belgium', 'Bermuda', 'Bohemia', 'Botswana', 'Brazil', 'Bulgaria', 'Burundi', 'Cameroon', 'Canada', 'Chile', 'China', 'Chinese Taipei', 'Colombia', 'Costa Rica', 'Croatia', 'Cuba', 'Cyprus', 'Czech Republic', 'Czechoslovakia', 'Denmark', 'Djibouti', 'Dominican Republic', 'East Germany', 'Ecuador', 'Egypt', 'Eritrea', 'Estonia', 'Ethiopia', 'Fiji', 'Finland', 'France', 'Gabon', 'Georgia', 'Germany', 'Ghana', 'Greece', 'Grenada', 'Guatemala', 'Guyana', 'Haiti', 'Hong Kong', 'Hungary', 'Iceland', 'Independent Olympic Athletes', 'India', 'Indonesia', 'Iran', 'Iraq', 'Ireland', 'Israel', 'Italy', 'Ivory Coast', 'Jamaica', 'Japan', 'Jordan', 'Kazakhstan', 'Kenya', 'Kosovo', 'Kuwait', 'Kyrgyzstan', 'Latvia', 'Lebanon', 'Liechtenstein', 'Lithuania', 'Luxembourg', 'Malaysia', 'Mauritius', 'Mexico', 'Moldova', 'Monaco', 'Mongolia', 'Montenegro', 'Morocco', 'Mozambique', 'Namibia', 'Nepal', 'Netherlands', 'Netherlands Antilles', 'New Zealand', 'Niger', 'Nigeria', 'North Korea', 'North Macedonia', 'Norway', 'Pakistan', 'Panama', 'Paraguay', 'Peru', 'Philippines', 'Poland', 'Portugal', 'Puerto Rico', 'Qatar', 'Romania', 'Russia', 'Saudi Arabia', 'Senegal', 'Serbia', 'Serbia and Montenegro', 'Singapore', 'Slovakia', 'Slovenia', 'South Africa', 'South Korea', 'Soviet Union', 'Spain', 'Sri Lanka', 'Sudan', 'Suriname', 'Sweden', 'Switzerland', 'Syria', 'Tajikistan', 'Tanzania', 'Thailand', 'Togo', 'Tonga', 'Trinidad and Tobago', 'Tunisia', 'Turkey', 'Uganda', 'Ukraine', 'Unified Team', 'United Arab Emirates', 'United Arab Republic', 'United Kingdom', 'United States of America', 'Uruguay', 'Uzbekistan', 'Venezuela', 'Vietnam', 'Virgin Islands', 'West Germany', 'West Indies Federation', 'Yugoslavia', 'Zambia', 'Zimbabwe'};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {'Afghanistan'};
            app.StartEditField = uieditfield(app.Question1, 'numeric');
            app.StartEditField.Value = 1800;
            app.EndEditField = uieditfield(app.Question1, 'numeric');
            app.EndEditField.Value = 2400;
            app.StartLabel = uilabel(app.Question1);
            app.StartLabel.Text = 'Start';
            app.EndLabel = uilabel(app.Question1);
            app.EndLabel.Text = 'End';
            app.ChooseuptoFourcountriesLabel = uilabel(app.Question1);
            app.ChooseuptoFourcountriesLabel.Text = 'Choose up to Four countries';
            app.GridCheckBox = uicheckbox(app.Question1);
            app.GridCheckBox.Text = 'Grid';
            app.Question2 = uitab(app.TabGroup);
            app.Question2.Title = 'Question 2';
            app.ofMedalsLabel = uilabel(app.Question2);
            app.ofMedalsLabel.Text = '# of Medals';
            app.Country_EditField = uieditfield(app.Question2, 'text');
            app.SportLabel = uilabel(app.Question2);
            app.SportLabel.Text = 'Sport :';
            app.AthleteLabel = uilabel(app.Question2);
            app.AthleteLabel.Text = 'Athlete:';
            app.Country_EditField_2 = uieditfield(app.Question2, 'text');
            app.Country_EditField_3 = uieditfield(app.Question2, 'text');
            app.Country_EditField_4 = uieditfield(app.Question2, 'text');
            app.Country_EditField_5 = uieditfield(app.Question2, 'text');
            app.Country_EditField_6 = uieditfield(app.Question2, 'text');
            app.Country_EditField_7 = uieditfield(app.Question2, 'text');
            app.Country_EditField_8 = uieditfield(app.Question2, 'text');
            app.Country_EditField_9 = uieditfield(app.Question2, 'text');
            app.Country_EditField_10 = uieditfield(app.Question2, 'text');
            app.Event2DropDownLabel = uilabel(app.Question2);
            app.Event2DropDownLabel.Text = 'Event #2';
            app.Event2DropDown = uidropdown(app.Question2);
            app.Event2DropDown.Items = {'Aeronautics', 'Alpine Skiing', 'Alpinism', 'Archery', 'Art Competitions', 'Athletics', 'Badminton', 'Baseball', 'Basque Pelota', 'Beach Volleyball', 'Biathlon', 'Bobsleigh', 'Boxing', 'Canoeing', 'Cricket', 'Croquet', 'Cross Country Skiing', 'Curling', 'Cycling', 'Diving', 'Equestrianism', 'Fencing', 'Figure Skating', 'Football', 'Freestyle Skiing', 'Golf', 'Gymnastics', 'Handball', 'Hockey', 'Ice Hockey', 'Jeu Du Paume', 'Judo', 'Lacrosse', 'Luge', 'Military Ski Control', 'Modern Pentathlon', 'Motorboating', 'Nordic Combined', 'Polo', 'Racquets', 'Rhythmic Gymnastics', 'Roque', 'Rowing', 'Rugby', 'Rugby Sevens', 'Sailing', 'Shooting', 'Short Track Speed Skating', 'Skeleton', 'Ski Jumping', 'Snowboarding', 'Softball', 'Speed Skating', 'Swimming', 'Synchronized Swimming', 'Table Tennis', 'Taekwondo', 'Tennis', 'Trampolining', 'Triathlon', 'Tug-Of-War Volleyball', 'Water Polo', 'Weightlifting Wrestling'};
            app.Event2DropDown.Value = 'Aeronautics';
            app.Event3DropDownLabel = uilabel(app.Question2);
            app.Event3DropDownLabel.Text = 'Event #3';
            app.Event3DropDown = uidropdown(app.Question2);
            app.Event3DropDown.Items = {'Aeronautics', 'Alpine Skiing', 'Alpinism', 'Archery', 'Art Competitions', 'Athletics', 'Badminton', 'Baseball', 'Basque Pelota', 'Beach Volleyball', 'Biathlon', 'Bobsleigh', 'Boxing', 'Canoeing', 'Cricket', 'Croquet', 'Cross Country Skiing', 'Curling', 'Cycling', 'Diving', 'Equestrianism', 'Fencing', 'Figure Skating', 'Football', 'Freestyle Skiing', 'Golf', 'Gymnastics', 'Handball', 'Hockey', 'Ice Hockey', 'Jeu Du Paume', 'Judo', 'Lacrosse', 'Luge', 'Military Ski Control', 'Modern Pentathlon', 'Motorboating', 'Nordic Combined', 'Polo', 'Racquets', 'Rhythmic Gymnastics', 'Roque', 'Rowing', 'Rugby', 'Rugby Sevens', 'Sailing', 'Shooting', 'Short Track Speed Skating', 'Skeleton', 'Ski Jumping', 'Snowboarding', 'Softball', 'Speed Skating', 'Swimming', 'Synchronized Swimming', 'Table Tennis', 'Taekwondo', 'Tennis', 'Trampolining', 'Triathlon', 'Tug-Of-War Volleyball', 'Water Polo', 'Weightlifting Wrestling'};
            app.Event3DropDown.Value = 'Aeronautics';
            app.Event4DropDownLabel = uilabel(app.Question2);
            app.Event4DropDownLabel.Text = 'Event #4';
            app.Event4DropDown = uidropdown(app.Question2);
            app.Event4DropDown.Items = {'Aeronautics', 'Alpine Skiing', 'Alpinism', 'Archery', 'Art Competitions', 'Athletics', 'Badminton', 'Baseball', 'Basque Pelota', 'Beach Volleyball', 'Biathlon', 'Bobsleigh', 'Boxing', 'Canoeing', 'Cricket', 'Croquet', 'Cross Country Skiing', 'Curling', 'Cycling', 'Diving', 'Equestrianism', 'Fencing', 'Figure Skating', 'Football', 'Freestyle Skiing', 'Golf', 'Gymnastics', 'Handball', 'Hockey', 'Ice Hockey', 'Jeu Du Paume', 'Judo', 'Lacrosse', 'Luge', 'Military Ski Control', 'Modern Pentathlon', 'Motorboating', 'Nordic Combined', 'Polo', 'Racquets', 'Rhythmic Gymnastics', 'Roque', 'Rowing', 'Rugby', 'Rugby Sevens', 'Sailing', 'Shooting', 'Short Track Speed Skating', 'Skeleton', 'Ski Jumping', 'Snowboarding', 'Softball', 'Speed Skating', 'Swimming', 'Synchronized Swimming', 'Table Tennis', 'Taekwondo', 'Tennis', 'Trampolining', 'Triathlon', 'Tug-Of-War Volleyball', 'Water Polo', 'Weightlifting Wrestling'};
            app.Event4DropDown.Value = 'Aeronautics';
            app.Event5DropDownLabel = uilabel(app.Question2);
            app.Event5DropDownLabel.Text = 'Event #5';
            app.Event5DropDown = uidropdown(app.Question2);
            app.Event5DropDown.Items = {'Aeronautics', 'Alpine Skiing', 'Alpinism', 'Archery', 'Art Competitions', 'Athletics', 'Badminton', 'Baseball', 'Basque Pelota', 'Beach Volleyball', 'Biathlon', 'Bobsleigh', 'Boxing', 'Canoeing', 'Cricket', 'Croquet', 'Cross Country Skiing', 'Curling', 'Cycling', 'Diving', 'Equestrianism', 'Fencing', 'Figure Skating', 'Football', 'Freestyle Skiing', 'Golf', 'Gymnastics', 'Handball', 'Hockey', 'Ice Hockey', 'Jeu Du Paume', 'Judo', 'Lacrosse', 'Luge', 'Military Ski Control', 'Modern Pentathlon', 'Motorboating', 'Nordic Combined', 'Polo', 'Racquets', 'Rhythmic Gymnastics', 'Roque', 'Rowing', 'Rugby', 'Rugby Sevens', 'Sailing', 'Shooting', 'Short Track Speed Skating', 'Skeleton', 'Ski Jumping', 'Snowboarding', 'Softball', 'Speed Skating', 'Swimming', 'Synchronized Swimming', 'Table Tennis', 'Taekwondo', 'Tennis', 'Trampolining', 'Triathlon', 'Tug-Of-War Volleyball', 'Water Polo', 'Weightlifting Wrestling'};
            app.Event5DropDown.Value = 'Aeronautics';
            app.Event1DropDownLabel = uilabel(app.Question2);
            app.Event1DropDownLabel.Text = 'Event #1';
            app.Event1DropDown = uidropdown(app.Question2);
            app.Event1DropDown.Items = {'Aeronautics', 'Alpine Skiing', 'Alpinism', 'Archery', 'Art Competitions', 'Athletics', 'Badminton', 'Baseball', 'Basque Pelota', 'Beach Volleyball', 'Biathlon', 'Bobsleigh', 'Boxing', 'Canoeing', 'Cricket', 'Croquet', 'Cross Country Skiing', 'Curling', 'Cycling', 'Diving', 'Equestrianism', 'Fencing', 'Figure Skating', 'Football', 'Freestyle Skiing', 'Golf', 'Gymnastics', 'Handball', 'Hockey', 'Ice Hockey', 'Jeu Du Paume', 'Judo', 'Lacrosse', 'Luge', 'Military Ski Control', 'Modern Pentathlon', 'Motorboating', 'Nordic Combined', 'Polo', 'Racquets', 'Rhythmic Gymnastics', 'Roque', 'Rowing', 'Rugby', 'Rugby Sevens', 'Sailing', 'Shooting', 'Short Track Speed Skating', 'Skeleton', 'Ski Jumping', 'Snowboarding', 'Softball', 'Speed Skating', 'Swimming', 'Synchronized Swimming', 'Table Tennis', 'Taekwondo', 'Tennis', 'Trampolining', 'Triathlon', 'Tug-Of-War Volleyball', 'Water Polo', 'Weightlifting Wrestling'};
            app.Event1DropDown.Value = 'Aeronautics';
            app.BronzeEditFieldLabel = uilabel(app.Question2);
            app.BronzeEditFieldLabel.Text = 'Bronze:';
            app.BronzeEditField = uieditfield(app.Question2, 'numeric');
            app.SilverEditFieldLabel = uilabel(app.Question2);
            app.SilverEditFieldLabel.Text = 'Silver:';
            app.SilverEditField = uieditfield(app.Question2, 'numeric');
            app.GoldEditFieldLabel = uilabel(app.Question2);
            app.GoldEditFieldLabel.Text = 'Gold:';
            app.GoldEditField = uieditfield(app.Question2, 'numeric');
            app.TotalEditFieldLabel = uilabel(app.Question2);
            app.TotalEditFieldLabel.Text = 'Total:';
            app.TotalEditField = uieditfield(app.Question2, 'numeric');
            app.BronzeEditField_2Label = uilabel(app.Question2);
            app.BronzeEditField_2Label.Text = 'Bronze:';
            app.BronzeEditField_2 = uieditfield(app.Question2, 'numeric');
            app.BronzeEditField_3Label = uilabel(app.Question2);
            app.BronzeEditField_3Label.Text = 'Bronze:';
            app.BronzeEditField_3 = uieditfield(app.Question2, 'numeric');
            app.BronzeEditField_4Label = uilabel(app.Question2);
            app.BronzeEditField_4Label.Text = 'Bronze:';
            app.BronzeEditField_4 = uieditfield(app.Question2, 'numeric');
            app.BronzeEditField_5Label = uilabel(app.Question2);
            app.BronzeEditField_5Label.Text = 'Bronze:';
            app.BronzeEditField_5 = uieditfield(app.Question2, 'numeric');
            app.SilverEditField_2Label = uilabel(app.Question2);
            app.SilverEditField_2Label.Text = 'Silver:';
            app.SilverEditField_2 = uieditfield(app.Question2, 'numeric');
            app.SilverEditField_3Label = uilabel(app.Question2);
            app.SilverEditField_3Label.Text = 'Silver:';
            app.SilverEditField_3 = uieditfield(app.Question2, 'numeric');
            app.SilverEditField_4Label = uilabel(app.Question2);
            app.SilverEditField_4Label.Text = 'Silver:';
            app.SilverEditField_4 = uieditfield(app.Question2, 'numeric');
            app.SilverEditField_5Label = uilabel(app.Question2);
            app.SilverEditField_5Label.Text = 'Silver:';
            app.SilverEditField_5 = uieditfield(app.Question2, 'numeric');
            app.GoldEditField_2Label = uilabel(app.Question2);
            app.GoldEditField_2Label.Text = 'Gold:';
            app.GoldEditField_2 = uieditfield(app.Question2, 'numeric');
            app.GoldEditField_3Label = uilabel(app.Question2);
            app.GoldEditField_3Label.Text = 'Gold:';
            app.GoldEditField_3 = uieditfield(app.Question2, 'numeric');
            app.GoldEditField_4Label = uilabel(app.Question2);
            app.GoldEditField_4Label.Text = 'Gold:';
            app.GoldEditField_4 = uieditfield(app.Question2, 'numeric');
            app.GoldEditField_5Label = uilabel(app.Question2);
            app.GoldEditField_5Label.Text = 'Gold:';
            app.GoldEditField_5 = uieditfield(app.Question2, 'numeric');
            app.TotalEditField_2Label = uilabel(app.Question2);
            app.TotalEditField_2Label.Text = 'Total:';
            app.TotalEditField_2 = uieditfield(app.Question2, 'numeric');
            app.TotalEditField_3Label = uilabel(app.Question2);
            app.TotalEditField_3Label.Text = 'Total:';
            app.TotalEditField_3 = uieditfield(app.Question2, 'numeric');
            app.TotalEditField_4Label = uilabel(app.Question2);
            app.TotalEditField_4Label.Text = 'Total:';
            app.TotalEditField_4 = uieditfield(app.Question2, 'numeric');
            app.TotalEditField_5Label = uilabel(app.Question2);
            app.TotalEditField_5Label.Text = 'Total:';
            app.TotalEditField_5 = uieditfield(app.Question2, 'numeric');
            app.CalculateButton = uibutton(app.Question2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Question3 = uitab(app.TabGroup);
            app.Question3.Title = 'Question 3';
            app.Country1DropDownLabel = uilabel(app.Question3);
            app.Country1DropDownLabel.Text = 'Country #1';
            app.Country1DropDown = uidropdown(app.Question3);
            app.Country1DropDown.Items = {'Afghanistan', 'Algeria', 'Argentina', 'Armenia', 'Australia', 'Australia and New Zealand', 'Austria', 'Azerbaijan', 'Bahamas', 'Bahrain', 'Barbados', 'Belarus', 'Belgium', 'Bermuda', 'Bohemia', 'Botswana', 'Brazil', 'Bulgaria', 'Burundi', 'Cameroon', 'Canada', 'Chile', 'China', 'Chinese Taipei', 'Colombia', 'Costa Rica', 'Croatia', 'Cuba', 'Cyprus', 'Czech Republic', 'Czechoslovakia', 'Denmark', 'Djibouti', 'Dominican Republic', 'East Germany', 'Ecuador', 'Egypt', 'Eritrea', 'Estonia', 'Ethiopia', 'Fiji', 'Finland', 'France', 'Gabon', 'Georgia', 'Germany', 'Ghana', 'Greece', 'Grenada', 'Guatemala', 'Guyana', 'Haiti', 'Hong Kong', 'Hungary', 'Iceland', 'Independent Olympic Athletes', 'India', 'Indonesia', 'Iran', 'Iraq', 'Ireland', 'Israel', 'Italy', 'Ivory Coast', 'Jamaica', 'Japan', 'Jordan', 'Kazakhstan', 'Kenya', 'Kosovo', 'Kuwait', 'Kyrgyzstan', 'Latvia', 'Lebanon', 'Liechtenstein', 'Lithuania', 'Luxembourg', 'Malaysia', 'Mauritius', 'Mexico', 'Moldova', 'Monaco', 'Mongolia', 'Montenegro', 'Morocco', 'Mozambique', 'Namibia', 'Nepal', 'Netherlands', 'Netherlands Antilles', 'New Zealand', 'Niger', 'Nigeria', 'North Korea', 'North Macedonia', 'Norway', 'Pakistan', 'Panama', 'Paraguay', 'Peru', 'Philippines', 'Poland', 'Portugal', 'Puerto Rico', 'Qatar', 'Romania', 'Russia', 'Saudi Arabia', 'Senegal', 'Serbia', 'Serbia and Montenegro', 'Singapore', 'Slovakia', 'Slovenia', 'South Africa', 'South Korea', 'Soviet Union', 'Spain', 'Sri Lanka', 'Sudan', 'Suriname', 'Sweden', 'Switzerland', 'Syria', 'Tajikistan', 'Tanzania', 'Thailand', 'Togo', 'Tonga', 'Trinidad and Tobago', 'Tunisia', 'Turkey', 'Uganda', 'Ukraine', 'Unified Team', 'United Arab Emirates', 'United Arab Republic', 'United Kingdom', 'United States of America', 'Uruguay', 'Uzbekistan', 'Venezuela', 'Vietnam', 'Virgin Islands', 'West Germany', 'West Indies Federation', 'Yugoslavia', 'Zambia', 'Zimbabwe'};
            app.Country1DropDown.Value = 'Afghanistan';
            app.Country2DropDownLabel = uilabel(app.Question3);
            app.Country2DropDownLabel.Text = 'Country #2';
            app.Country2DropDown = uidropdown(app.Question3);
            app.Country2DropDown.Items = {'Afghanistan', 'Algeria', 'Argentina', 'Armenia', 'Australia', 'Australia and New Zealand', 'Austria', 'Azerbaijan', 'Bahamas', 'Bahrain', 'Barbados', 'Belarus', 'Belgium', 'Bermuda', 'Bohemia', 'Botswana', 'Brazil', 'Bulgaria', 'Burundi', 'Cameroon', 'Canada', 'Chile', 'China', 'Chinese Taipei', 'Colombia', 'Costa Rica', 'Croatia', 'Cuba', 'Cyprus', 'Czech Republic', 'Czechoslovakia', 'Denmark', 'Djibouti', 'Dominican Republic', 'East Germany', 'Ecuador', 'Egypt', 'Eritrea', 'Estonia', 'Ethiopia', 'Fiji', 'Finland', 'France', 'Gabon', 'Georgia', 'Germany', 'Ghana', 'Greece', 'Grenada', 'Guatemala', 'Guyana', 'Haiti', 'Hong Kong', 'Hungary', 'Iceland', 'Independent Olympic Athletes', 'India', 'Indonesia', 'Iran', 'Iraq', 'Ireland', 'Israel', 'Italy', 'Ivory Coast', 'Jamaica', 'Japan', 'Jordan', 'Kazakhstan', 'Kenya', 'Kosovo', 'Kuwait', 'Kyrgyzstan', 'Latvia', 'Lebanon', 'Liechtenstein', 'Lithuania', 'Luxembourg', 'Malaysia', 'Mauritius', 'Mexico', 'Moldova', 'Monaco', 'Mongolia', 'Montenegro', 'Morocco', 'Mozambique', 'Namibia', 'Nepal', 'Netherlands', 'Netherlands Antilles', 'New Zealand', 'Niger', 'Nigeria', 'North Korea', 'North Macedonia', 'Norway', 'Pakistan', 'Panama', 'Paraguay', 'Peru', 'Philippines', 'Poland', 'Portugal', 'Puerto Rico', 'Qatar', 'Romania', 'Russia', 'Saudi Arabia', 'Senegal', 'Serbia', 'Serbia and Montenegro', 'Singapore', 'Slovakia', 'Slovenia', 'South Africa', 'South Korea', 'Soviet Union', 'Spain', 'Sri Lanka', 'Sudan', 'Suriname', 'Sweden', 'Switzerland', 'Syria', 'Tajikistan', 'Tanzania', 'Thailand', 'Togo', 'Tonga', 'Trinidad and Tobago', 'Tunisia', 'Turkey', 'Uganda', 'Ukraine', 'Unified Team', 'United Arab Emirates', 'United Arab Republic', 'United Kingdom', 'United States of America', 'Uruguay', 'Uzbekistan', 'Venezuela', 'Vietnam', 'Virgin Islands', 'West Germany', 'West Indies Federation', 'Yugoslavia', 'Zambia', 'Zimbabwe'};
            app.Country2DropDown.Value = 'Afghanistan';
            app.Country3DropDownLabel = uilabel(app.Question3);
            app.Country3DropDownLabel.Text = 'Country #3';
            app.Country3DropDown = uidropdown(app.Question3);
            app.Country3DropDown.Items = {'Afghanistan', 'Algeria', 'Argentina', 'Armenia', 'Australia', 'Australia and New Zealand', 'Austria', 'Azerbaijan', 'Bahamas', 'Bahrain', 'Barbados', 'Belarus', 'Belgium', 'Bermuda', 'Bohemia', 'Botswana', 'Brazil', 'Bulgaria', 'Burundi', 'Cameroon', 'Canada', 'Chile', 'China', 'Chinese Taipei', 'Colombia', 'Costa Rica', 'Croatia', 'Cuba', 'Cyprus', 'Czech Republic', 'Czechoslovakia', 'Denmark', 'Djibouti', 'Dominican Republic', 'East Germany', 'Ecuador', 'Egypt', 'Eritrea', 'Estonia', 'Ethiopia', 'Fiji', 'Finland', 'France', 'Gabon', 'Georgia', 'Germany', 'Ghana', 'Greece', 'Grenada', 'Guatemala', 'Guyana', 'Haiti', 'Hong Kong', 'Hungary', 'Iceland', 'Independent Olympic Athletes', 'India', 'Indonesia', 'Iran', 'Iraq', 'Ireland', 'Israel', 'Italy', 'Ivory Coast', 'Jamaica', 'Japan', 'Jordan', 'Kazakhstan', 'Kenya', 'Kosovo', 'Kuwait', 'Kyrgyzstan', 'Latvia', 'Lebanon', 'Liechtenstein', 'Lithuania', 'Luxembourg', 'Malaysia', 'Mauritius', 'Mexico', 'Moldova', 'Monaco', 'Mongolia', 'Montenegro', 'Morocco', 'Mozambique', 'Namibia', 'Nepal', 'Netherlands', 'Netherlands Antilles', 'New Zealand', 'Niger', 'Nigeria', 'North Korea', 'North Macedonia', 'Norway', 'Pakistan', 'Panama', 'Paraguay', 'Peru', 'Philippines', 'Poland', 'Portugal', 'Puerto Rico', 'Qatar', 'Romania', 'Russia', 'Saudi Arabia', 'Senegal', 'Serbia', 'Serbia and Montenegro', 'Singapore', 'Slovakia', 'Slovenia', 'South Africa', 'South Korea', 'Soviet Union', 'Spain', 'Sri Lanka', 'Sudan', 'Suriname', 'Sweden', 'Switzerland', 'Syria', 'Tajikistan', 'Tanzania', 'Thailand', 'Togo', 'Tonga', 'Trinidad and Tobago', 'Tunisia', 'Turkey', 'Uganda', 'Ukraine', 'Unified Team', 'United Arab Emirates', 'United Arab Republic', 'United Kingdom', 'United States of America', 'Uruguay', 'Uzbekistan', 'Venezuela', 'Vietnam', 'Virgin Islands', 'West Germany', 'West Indies Federation', 'Yugoslavia', 'Zambia', 'Zimbabwe'};
            app.Country3DropDown.Value = 'Afghanistan';
            app.Country4DropDownLabel = uilabel(app.Question3);
            app.Country4DropDownLabel.Text = 'Country #4';
            app.Country4DropDown = uidropdown(app.Question3);
            app.Country4DropDown.Items = {'Afghanistan', 'Algeria', 'Argentina', 'Armenia', 'Australia', 'Australia and New Zealand', 'Austria', 'Azerbaijan', 'Bahamas', 'Bahrain', 'Barbados', 'Belarus', 'Belgium', 'Bermuda', 'Bohemia', 'Botswana', 'Brazil', 'Bulgaria', 'Burundi', 'Cameroon', 'Canada', 'Chile', 'China', 'Chinese Taipei', 'Colombia', 'Costa Rica', 'Croatia', 'Cuba', 'Cyprus', 'Czech Republic', 'Czechoslovakia', 'Denmark', 'Djibouti', 'Dominican Republic', 'East Germany', 'Ecuador', 'Egypt', 'Eritrea', 'Estonia', 'Ethiopia', 'Fiji', 'Finland', 'France', 'Gabon', 'Georgia', 'Germany', 'Ghana', 'Greece', 'Grenada', 'Guatemala', 'Guyana', 'Haiti', 'Hong Kong', 'Hungary', 'Iceland', 'Independent Olympic Athletes', 'India', 'Indonesia', 'Iran', 'Iraq', 'Ireland', 'Israel', 'Italy', 'Ivory Coast', 'Jamaica', 'Japan', 'Jordan', 'Kazakhstan', 'Kenya', 'Kosovo', 'Kuwait', 'Kyrgyzstan', 'Latvia', 'Lebanon', 'Liechtenstein', 'Lithuania', 'Luxembourg', 'Malaysia', 'Mauritius', 'Mexico', 'Moldova', 'Monaco', 'Mongolia', 'Montenegro', 'Morocco', 'Mozambique', 'Namibia', 'Nepal', 'Netherlands', 'Netherlands Antilles', 'New Zealand', 'Niger', 'Nigeria', 'North Korea', 'North Macedonia', 'Norway', 'Pakistan', 'Panama', 'Paraguay', 'Peru', 'Philippines', 'Poland', 'Portugal', 'Puerto Rico', 'Qatar', 'Romania', 'Russia', 'Saudi Arabia', 'Senegal', 'Serbia', 'Serbia and Montenegro', 'Singapore', 'Slovakia', 'Slovenia', 'South Africa', 'South Korea', 'Soviet Union', 'Spain', 'Sri Lanka', 'Sudan', 'Suriname', 'Sweden', 'Switzerland', 'Syria', 'Tajikistan', 'Tanzania', 'Thailand', 'Togo', 'Tonga', 'Trinidad and Tobago', 'Tunisia', 'Turkey', 'Uganda', 'Ukraine', 'Unified Team', 'United Arab Emirates', 'United Arab Republic', 'United Kingdom', 'United States of America', 'Uruguay', 'Uzbekistan', 'Venezuela', 'Vietnam', 'Virgin Islands', 'West Germany', 'West Indies Federation', 'Yugoslavia', 'Zambia', 'Zimbabwe'};
            app.Country4DropDown.Value = 'Afghanistan';
            app.Country5DropDownLabel = uilabel(app.Question3);
            app.Country5DropDownLabel.Text = 'Country #5';
            app.Country5DropDown = uidropdown(app.Question3);
            app.Country5DropDown.Items = {'Afghanistan', 'Algeria', 'Argentina', 'Armenia', 'Australia', 'Australia and New Zealand', 'Austria', 'Azerbaijan', 'Bahamas', 'Bahrain', 'Barbados', 'Belarus', 'Belgium', 'Bermuda', 'Bohemia', 'Botswana', 'Brazil', 'Bulgaria', 'Burundi', 'Cameroon', 'Canada', 'Chile', 'China', 'Chinese Taipei', 'Colombia', 'Costa Rica', 'Croatia', 'Cuba', 'Cyprus', 'Czech Republic', 'Czechoslovakia', 'Denmark', 'Djibouti', 'Dominican Republic', 'East Germany', 'Ecuador', 'Egypt', 'Eritrea', 'Estonia', 'Ethiopia', 'Fiji', 'Finland', 'France', 'Gabon', 'Georgia', 'Germany', 'Ghana', 'Greece', 'Grenada', 'Guatemala', 'Guyana', 'Haiti', 'Hong Kong', 'Hungary', 'Iceland', 'Independent Olympic Athletes', 'India', 'Indonesia', 'Iran', 'Iraq', 'Ireland', 'Israel', 'Italy', 'Ivory Coast', 'Jamaica', 'Japan', 'Jordan', 'Kazakhstan', 'Kenya', 'Kosovo', 'Kuwait', 'Kyrgyzstan', 'Latvia', 'Lebanon', 'Liechtenstein', 'Lithuania', 'Luxembourg', 'Malaysia', 'Mauritius', 'Mexico', 'Moldova', 'Monaco', 'Mongolia', 'Montenegro', 'Morocco', 'Mozambique', 'Namibia', 'Nepal', 'Netherlands', 'Netherlands Antilles', 'New Zealand', 'Niger', 'Nigeria', 'North Korea', 'North Macedonia', 'Norway', 'Pakistan', 'Panama', 'Paraguay', 'Peru', 'Philippines', 'Poland', 'Portugal', 'Puerto Rico', 'Qatar', 'Romania', 'Russia', 'Saudi Arabia', 'Senegal', 'Serbia', 'Serbia and Montenegro', 'Singapore', 'Slovakia', 'Slovenia', 'South Africa', 'South Korea', 'Soviet Union', 'Spain', 'Sri Lanka', 'Sudan', 'Suriname', 'Sweden', 'Switzerland', 'Syria', 'Tajikistan', 'Tanzania', 'Thailand', 'Togo', 'Tonga', 'Trinidad and Tobago', 'Tunisia', 'Turkey', 'Uganda', 'Ukraine', 'Unified Team', 'United Arab Emirates', 'United Arab Republic', 'United Kingdom', 'United States of America', 'Uruguay', 'Uzbekistan', 'Venezuela', 'Vietnam', 'Virgin Islands', 'West Germany', 'West Indies Federation', 'Yugoslavia', 'Zambia', 'Zimbabwe'};
            app.Country5DropDown.Value = 'Afghanistan';
            app.BronzeEditField_6Label = uilabel(app.Question3);
            app.BronzeEditField_6Label.Text = 'Bronze:';
            app.BronzeEditField_6 = uieditfield(app.Question3, 'numeric');
            app.SilverEditField_6Label = uilabel(app.Question3);
            app.SilverEditField_6Label.Text = 'Silver:';
            app.SilverEditField_6 = uieditfield(app.Question3, 'numeric');
            app.GoldEditField_6Label = uilabel(app.Question3);
            app.GoldEditField_6Label.Text = 'Gold:';
            app.GoldEditField_6 = uieditfield(app.Question3, 'numeric');
            app.TotalEditField_6Label = uilabel(app.Question3);
            app.TotalEditField_6Label.Text = 'Total:';
            app.TotalEditField_6 = uieditfield(app.Question3, 'numeric');
            app.BronzeEditField_7Label = uilabel(app.Question3);
            app.BronzeEditField_7Label.Text = 'Bronze:';
            app.BronzeEditField_7 = uieditfield(app.Question3, 'numeric');
            app.BronzeEditField_8Label = uilabel(app.Question3);
            app.BronzeEditField_8Label.Text = 'Bronze:';
            app.BronzeEditField_8 = uieditfield(app.Question3, 'numeric');
            app.BronzeEditField_9Label = uilabel(app.Question3);
            app.BronzeEditField_9Label.Text = 'Bronze:';
            app.BronzeEditField_9 = uieditfield(app.Question3, 'numeric');
            app.BronzeEditField_10Label = uilabel(app.Question3);
            app.BronzeEditField_10Label.Text = 'Bronze:';
            app.BronzeEditField_10 = uieditfield(app.Question3, 'numeric');
            app.SilverEditField_7Label = uilabel(app.Question3);
            app.SilverEditField_7Label.Text = 'Silver:';
            app.SilverEditField_7 = uieditfield(app.Question3, 'numeric');
            app.SilverEditField_8Label = uilabel(app.Question3);
            app.SilverEditField_8Label.Text = 'Silver:';
            app.SilverEditField_8 = uieditfield(app.Question3, 'numeric');
            app.SilverEditField_9Label = uilabel(app.Question3);
            app.SilverEditField_9Label.Text = 'Silver:';
            app.SilverEditField_9 = uieditfield(app.Question3, 'numeric');
            app.SilverEditField_10Label = uilabel(app.Question3);
            app.SilverEditField_10Label.Text = 'Silver:';
            app.SilverEditField_10 = uieditfield(app.Question3, 'numeric');
            app.GoldEditField_7Label = uilabel(app.Question3);
            app.GoldEditField_7Label.Text = 'Gold:';
            app.GoldEditField_7 = uieditfield(app.Question3, 'numeric');
            app.GoldEditField_8Label = uilabel(app.Question3);
            app.GoldEditField_8Label.Text = 'Gold:';
            app.GoldEditField_8 = uieditfield(app.Question3, 'numeric');
            app.GoldEditField_9Label = uilabel(app.Question3);
            app.GoldEditField_9Label.Text = 'Gold:';
            app.GoldEditField_9 = uieditfield(app.Question3, 'numeric');
            app.GoldEditField_10Label = uilabel(app.Question3);
            app.GoldEditField_10Label.Text = 'Gold:';
            app.GoldEditField_10 = uieditfield(app.Question3, 'numeric');
            app.TotalEditField_7Label = uilabel(app.Question3);
            app.TotalEditField_7Label.Text = 'Total:';
            app.TotalEditField_7 = uieditfield(app.Question3, 'numeric');
            app.TotalEditField_8Label = uilabel(app.Question3);
            app.TotalEditField_8Label.Text = 'Total:';
            app.TotalEditField_8 = uieditfield(app.Question3, 'numeric');
            app.TotalEditField_9Label = uilabel(app.Question3);
            app.TotalEditField_9Label.Text = 'Total:';
            app.TotalEditField_9 = uieditfield(app.Question3, 'numeric');
            app.TotalEditField_10Label = uilabel(app.Question3);
            app.TotalEditField_10Label.Text = 'Total:';
            app.TotalEditField_10 = uieditfield(app.Question3, 'numeric');
            app.CountryNameLabel = uilabel(app.Question3);
            app.CountryNameLabel.Text = 'Country Name: ';
            app.ofMedalsLabel_2 = uilabel(app.Question3);
            app.ofMedalsLabel_2.Text = '# of Medals';
            app.ComputeButton = uibutton(app.Question3, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.EditField = uieditfield(app.Question3, 'text');
            app.EditField_2 = uieditfield(app.Question3, 'text');
            app.EditField_3 = uieditfield(app.Question3, 'text');
            app.EditField_4 = uieditfield(app.Question3, 'text');
            app.EditField_5 = uieditfield(app.Question3, 'text');
            app.Question4 = uitab(app.TabGroup);
            app.Question4.Title = 'Question 4';
            app.UIAxes2 = uiaxes(app.Question4);
            title(app.UIAxes2, 'Age Based Performance Comparison Chart')
            xlabel(app.UIAxes2, 'Age Groups')
            ylabel(app.UIAxes2, 'Frequency')
            zlabel(app.UIAxes2, 'Z')
            app.GenderDropDownLabel = uilabel(app.Question4);
            app.GenderDropDownLabel.Text = 'Gender';
            app.GenderDropDown = uidropdown(app.Question4);
            app.GenderDropDown.Items = {'Men', 'Women'};
            app.GenderDropDown.Value = 'Men';
            app.SportDropDown_2Label = uilabel(app.Question4);
            app.SportDropDown_2Label.Text = 'Sport';
            app.SportDropDown_2 = uidropdown(app.Question4);
            app.SportDropDown_2.Items = {'Basketball', 'Basque Pelota', 'Beach Volleyball', 'Biathlon', 'Bobsleigh', 'Boxing', 'Canoeing', 'Cricket', 'Croquet', 'Cross Country Skiing', 'Curling', 'Cycling', 'Diving', 'Equestrianism', 'Fencing', 'Figure Skating', 'Football', 'Freestyle Skiing', 'Golf', 'Gymnastics', 'Handball', 'Hockey', 'Ice Hockey', 'Jeu De Paume', 'Judo', 'Lacrosse', 'Luge', 'Military Ski Patrol', 'Modern Pentathlon', 'Motorboating', 'Nordic Combined', 'Polo', 'Racquets', 'Rhythmic Gymnastics', 'Roque', 'Rowing', 'Rugby', 'Rugby Sevens', 'Sailing', 'Shooting', 'Short Track Speed Skating', 'Skeleton', 'Ski Jumping', 'Snowboarding', 'Softball', 'Speed Skating', 'Swimming', 'Synchronized Swimming', 'Table Tennis', 'Taekwondo', 'Tennis', 'Trampolining', 'Triathlon', 'Tug-Of-War', 'Volleyball', 'Water Polo', 'Weightlifting', 'Wrestling'};
            app.SportDropDown_2.Value = 'Basketball';
            app.MedalDropDown_2Label = uilabel(app.Question4);
            app.MedalDropDown_2Label.Text = 'Medal';
            app.MedalDropDown_2 = uidropdown(app.Question4);
            app.MedalDropDown_2.Items = {'Gold', 'Silver', 'Bronze'};
            app.MedalDropDown_2.Value = 'Gold';
            app.GraphButton_2 = uibutton(app.Question4, 'push');
            app.GraphButton_2.ButtonPushedFcn = createCallbackFcn(app, @GraphButton_2Pushed, true);
            app.GraphButton_2.Text = 'Graph';
            app.GridCheckBox_2 = uicheckbox(app.Question4);
            app.GridCheckBox_2.Text = 'Grid';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end